classdef BioMectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        COMPORTEditField        matlab.ui.control.NumericEditField
        COMPORTEditFieldLabel   matlab.ui.control.Label
        ConnectedLamp           matlab.ui.control.Lamp
        ConnectedLampLabel      matlab.ui.control.Label
        SessionStatusLamp       matlab.ui.control.Lamp
        SessionStatusLampLabel  matlab.ui.control.Label
        TimevalLabel            matlab.ui.control.Label
        TimerLabel              matlab.ui.control.Label
        ConnectButton           matlab.ui.control.Button
        HealthParametersPanel   matlab.ui.container.Panel
        StatetextLabel          matlab.ui.control.Label
        CognitiveStateLabel     matlab.ui.control.Label
        rmmsLabel               matlab.ui.control.Label
        RMSSDLabel              matlab.ui.control.Label
        rpbreathsminLabel       matlab.ui.control.Label
        RespiratoryRateLabel    matlab.ui.control.Label
        sdmsLabel               matlab.ui.control.Label
        SDNNLabel               matlab.ui.control.Label
        spLabel                 matlab.ui.control.Label
        SpO2Label               matlab.ui.control.Label
        hrbpmLabel              matlab.ui.control.Label
        HeartRateLabel          matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        HealthParametersNormalValueRangesPanel  matlab.ui.container.Panel
        NormalRangesLabel       matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        HRVvaluesforDifferentCognitiveStatesLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IsRunning = false; % Tracks if the session is active
        IsConnected = false; % Tracks the serial port connection
        Device = []; % Serial port object
    end
    
    properties (Constant)
        BAUD_RATE = 115200;
        SAMPLE_RATE = 50;
        LOGGING_INTERVAL_SEC = 2;
        ANALYSIS_INTERVAL_SEC = 15;
    end

    methods (Access = private)
        function startupFcn(app)
            % Set initial state of buttons
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            disp("App started. Please connect to the device.");
        end
        % Callback function for the Connect button
        function ConnectButtonPushed(app, event)
            if ~app.IsConnected
                try
                    portNumber = app.COMPORTEditField.Value;
                    comPortString = sprintf('COM%d', portNumber);
                    app.Device = serialport(comPortString, app.BAUD_RATE);
                    configureTerminator(app.Device, "LF");
                    app.ConnectedLamp.Color = 'g';
                    app.ConnectButton.Text = 'Disconnect';
                    app.StartButton.Enable = 'on';
                    app.IsConnected = true;
                    disp(['Connected to Raspberry Pi Pico on ', comPortString]);
                catch e
                    uialert(app.UIFigure, ['Failed to connect: ', e.message], 'Connection Error');
                end
            else
                delete(app.Device); % Use delete for the serial object
                app.Device = [];
                app.ConnectedLamp.Color = 'r';
                app.ConnectButton.Text = 'Connect';
                app.StartButton.Enable = 'off';
                app.IsConnected = false;
                disp("Disconnected from device.");
            end
        end

        % Callback function for the Start button
        function StartButtonPushed(app, event)
            app.IsRunning = true;
            app.StartButton.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.SessionStatusLamp.Color = 'g';

            cla(app.UIAxes); % Clear the axes
            hPlot = animatedline(app.UIAxes, 'Color', 'b');
            dataLogBuffer = [];

            irChngStor = zeros(1, 5); prev_ir = 0;
            bpmStor = zeros(1, 10); spO2Stor = zeros(1, 20);
            lookpeak = true; beat_timer = tic;
            irHigh = 0; irLow = inf; redHigh = 0; redLow = inf;
            ir_window_buffer = []; rr_interval_buffer = [];
            analysis_timer = tic; log_timer = tic;

            heartRate = NaN; spO2 = NaN; rmssd = NaN; sdnn = NaN; respRate = NaN;

            sampleCount = 0; sessionStartTime = tic;
            disp("Session Started...");

            while app.IsRunning
               line = readline(app.Device);
                if isstring(line) && strlength(line) > 0
                    newData = str2double(split(line, ','));
                    if numel(newData) == 2 && ~any(isnan(newData))
                        irValue = newData(1); redValue = newData(2);
                        sampleCount = sampleCount + 1;
                        addpoints(hPlot, sampleCount, irValue);

                        ir_window_buffer(end+1) = irValue;
                        if irValue > irHigh, irHigh = irValue; end; if irValue < irLow, irLow = irValue; end
                        if redValue > redHigh, redHigh = redValue; end; if redValue < redLow, redLow = redValue; end

                        ir_change = irValue - prev_ir; prev_ir = irValue;
                        irChngStor = [irChngStor(2:end), ir_change]; avgChng = mean(irChngStor);

                        if avgChng < -5 && lookpeak
                            interval_sec = toc(beat_timer);
                            if interval_sec > 0.33
                                bpm = 60 / interval_sec;
                                beat_timer = tic; lookpeak = false;
                                rr_interval_buffer(end+1) = interval_sec * 1000;

                                bpmStor = [bpmStor(2:end), bpm];
                                active_bpm = bpmStor(bpmStor > 0);
                                if numel(active_bpm) > 2, heartRate = mean(sort(active_bpm(2:end-1))); else, heartRate = mean(active_bpm); end

                                current_spo2 = app.calculate_spo2(redHigh, redLow, irHigh, irLow);
                                if current_spo2 > 80, spO2Stor = [spO2Stor(2:end), current_spo2]; end
                                active_spo2 = spO2Stor(spO2Stor > 0);
                                if numel(active_spo2) > 2, spO2 = mean(sort(active_spo2(2:end-1))); else, spO2 = mean(active_spo2); end

                                irHigh = 0; irLow = inf; redHigh = 0; redLow = inf;
                            end
                        end
                        if avgChng > 0.5 && ~lookpeak, lookpeak = true; end

                        if toc(analysis_timer) > app.ANALYSIS_INTERVAL_SEC
                            if numel(rr_interval_buffer) > 5
                                cleaned_rr = app.clean_rr_intervals(rr_interval_buffer);
                                if numel(cleaned_rr) > 4, rmssd = sqrt(mean(diff(cleaned_rr).^2)); sdnn = std(cleaned_rr); end
                            end
                            if numel(ir_window_buffer) > 100
                                [up,~] = envelope(ir_window_buffer, 25, 'peak');
                                [~, locs] = findpeaks(up - mean(up), 'MinPeakDistance', 100);
                                if numel(locs) > 1, respRate = 60 / (mean(diff(locs))/app.SAMPLE_RATE); end
                            end
                            ir_window_buffer = []; rr_interval_buffer = []; analysis_timer = tic;
                        end

                        if toc(log_timer) > app.LOGGING_INTERVAL_SEC
                            currentTime = toc(sessionStartTime);
                            dataLogBuffer(end+1, :) = [currentTime, irValue, redValue, heartRate, spO2, rmssd, sdnn, respRate];
                            log_timer = tic;
                        end

                        % Update UI Labels
                        if ~isnan(heartRate), app.hrbpmLabel.Text = sprintf('%.1f bpm', heartRate); end
                        if ~isnan(spO2), app.spLabel.Text = sprintf('%.1f %%', spO2); end
                        if ~isnan(rmssd), app.rmmsLabel.Text = sprintf('%.1f ms', rmssd); end
                        if ~isnan(sdnn), app.sdmsLabel.Text = sprintf('%.1f ms', sdnn); end
                        if ~isnan(respRate), app.rpbreathsminLabel.Text = sprintf('%.1f breaths/min', respRate); end

                        if ~isnan(rmssd)
                            if rmssd > 50
                                app.StatetextLabel.Text = 'RELAXED';
                            elseif rmssd < 20
                                app.StatetextLabel.Text = 'STRESSED / FATIGUED';
                            else
                                app.StatetextLabel.Text = 'FOCUSED / ACTIVE';
                            end
                        end

                        elapsedTime = toc(sessionStartTime);
                        mins = floor(elapsedTime / 60); secs = floor(mod(elapsedTime, 60));
                        app.TimevalLabel.Text = sprintf('%02d:%02d', mins, secs); % Update the timer's value label
                        drawnow('limitrate'); % Allow UI to update
                    end
                end
            end
            app.saveData(dataLogBuffer);
        end

        % Callback function for the Stop button
        function StopButtonPushed(app, event)
            if ~app.IsRunning, return; end
            app.IsRunning = false;
            app.StopButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.SessionStatusLamp.Color = 'r'; % Session is stopped
        end

        % Function to save data to a CSV file
        function saveData(app, logBuffer)
            % Check if there is actually data to save
            if ~isempty(logBuffer)
                timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                filename = ['Session_Data_', timestamp, '.csv'];
                try
                    T = array2table(logBuffer, 'VariableNames', {'Timestamp', 'IR', 'Red', 'HR', 'SpO2', 'RMSSD', 'SDNN', 'RespRate'});
                    writetable(T, filename);
                    disp(['Session stopped. Data saved to ', filename]);
                    assignin('base', 'dataLogBuffer', []); 
                catch e
                    disp(['Error saving data: ', e.message]);
                end
            else
                disp('Session stopped. No new data to save.');
            end
        end

        % Function to clean RR intervals
        function cleaned = clean_rr_intervals(app, intervals)
            if isempty(intervals), cleaned = []; return; end
            median_rr = median(intervals);
            min_rr = median_rr * 0.80; max_rr = median_rr * 1.20;
            cleaned = intervals(intervals > min_rr & intervals < max_rr);
        end

        % Function to calculate SpO2
        function spO2 = calculate_spo2(app, red_max, red_min, ir_max, ir_min)
            red_AC = red_max - red_min; red_DC = (red_max + red_min) / 2;
            ir_AC = ir_max - ir_min; ir_DC = (ir_max + ir_min) / 2;
            if red_DC == 0 || ir_DC == 0 || ir_AC == 0, spO2 = 0; return; end
            R_val = (red_AC / red_DC) / (ir_AC / ir_DC);
            spO2 = 116.6 - (34.5 * R_val);
            if spO2 > 100, spO2 = 100; end
        end
        
        % Code that executes when the app window is closed
        function UIFigureCloseRequest(app, event)
            app.IsRunning = false; % Stop the session if it's running
            if app.IsConnected
                delete(app.Device); % Properly close the serial connection
            end
            delete(app); % Delete the app object
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            StartButtonPushed(app, event);
        end

        % Button pushed function: StopButton
        function StopButtonPushed2(app, event)
            StopButtonPushed(app, event);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed2(app, event)
            ConnectButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PPG Signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.HealthParametersNormalValueRangesPanel = uipanel(app.UIFigure);
            app.HealthParametersNormalValueRangesPanel.Title = 'Health Parameters Normal Value Ranges';
            app.HRVvaluesforDifferentCognitiveStatesLabel = uilabel(app.HealthParametersNormalValueRangesPanel);
            app.HRVvaluesforDifferentCognitiveStatesLabel.Text = 'HRV values for Different Cognitive States';
            app.Image = uiimage(app.HealthParametersNormalValueRangesPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2025-10-10 194353.png');
            app.Image2 = uiimage(app.HealthParametersNormalValueRangesPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2025-10-10 194715.png');
            app.NormalRangesLabel = uilabel(app.HealthParametersNormalValueRangesPanel);
            app.NormalRangesLabel.Text = 'Normal Ranges';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed2, true);
            app.StopButton.Text = 'Stop';
            app.HealthParametersPanel = uipanel(app.UIFigure);
            app.HealthParametersPanel.Title = 'Health Parameters';
            app.HeartRateLabel = uilabel(app.HealthParametersPanel);
            app.HeartRateLabel.Text = 'Heart Rate:';
            app.hrbpmLabel = uilabel(app.HealthParametersPanel);
            app.hrbpmLabel.Text = 'hr bpm';
            app.SpO2Label = uilabel(app.HealthParametersPanel);
            app.SpO2Label.Text = 'SpO2:';
            app.spLabel = uilabel(app.HealthParametersPanel);
            app.spLabel.Text = 'sp %';
            app.SDNNLabel = uilabel(app.HealthParametersPanel);
            app.SDNNLabel.Text = 'SDNN:';
            app.sdmsLabel = uilabel(app.HealthParametersPanel);
            app.sdmsLabel.Text = 'sd ms';
            app.RespiratoryRateLabel = uilabel(app.HealthParametersPanel);
            app.RespiratoryRateLabel.Text = 'Respiratory Rate:';
            app.rpbreathsminLabel = uilabel(app.HealthParametersPanel);
            app.rpbreathsminLabel.Text = 'rp breaths/min';
            app.RMSSDLabel = uilabel(app.HealthParametersPanel);
            app.RMSSDLabel.Text = 'RMSSD:';
            app.rmmsLabel = uilabel(app.HealthParametersPanel);
            app.rmmsLabel.Text = 'rm ms';
            app.CognitiveStateLabel = uilabel(app.HealthParametersPanel);
            app.CognitiveStateLabel.Text = 'Cognitive State:';
            app.StatetextLabel = uilabel(app.HealthParametersPanel);
            app.StatetextLabel.Text = 'Statetext';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed2, true);
            app.ConnectButton.Text = 'Connect';
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = 'Timer: ';
            app.TimevalLabel = uilabel(app.UIFigure);
            app.TimevalLabel.Text = 'Timeval';
            app.SessionStatusLampLabel = uilabel(app.UIFigure);
            app.SessionStatusLampLabel.Text = 'Session Status';
            app.SessionStatusLamp = uilamp(app.UIFigure);
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.COMPORTEditFieldLabel = uilabel(app.UIFigure);
            app.COMPORTEditFieldLabel.Text = 'COM PORT';
            app.COMPORTEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BioMectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end